classdef design_page_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SubmitButton                    matlab.ui.control.Button
        Q1WhatisyourfavoritefoodButtonGroup  matlab.ui.container.ButtonGroup
        MexicanfoodButton               matlab.ui.control.RadioButton
        pastaButton                     matlab.ui.control.RadioButton
        WhoneedsfoodwhenyouhavebeerButton  matlab.ui.control.RadioButton
        fastfoodButton                  matlab.ui.control.RadioButton
        fruitsandveggiesButton          matlab.ui.control.RadioButton
        Q2WhatisyourfavoriteseasonButtonGroup  matlab.ui.container.ButtonGroup
        IlikethemallButton              matlab.ui.control.RadioButton
        fallButton                      matlab.ui.control.RadioButton
        springButton                    matlab.ui.control.RadioButton
        winterButton                    matlab.ui.control.RadioButton
        summerButton                    matlab.ui.control.RadioButton
        Q3WhatwouldyouridealfirstdatebeButtonGroup  matlab.ui.container.ButtonGroup
        goingtoacarnivalButton          matlab.ui.control.RadioButton
        fancydinnerdateButton           matlab.ui.control.RadioButton
        stayinginandplayinggamesButton  matlab.ui.control.RadioButton
        goingforadriveButton            matlab.ui.control.RadioButton
        iceskatingButton                matlab.ui.control.RadioButton
        Q8WhichpetwouldyouratherhaveButtonGroup_3  matlab.ui.container.ButtonGroup
        APMAEconwithanentrepreneurshipcertificateButton  matlab.ui.control.RadioButton
        VISAButton                      matlab.ui.control.RadioButton
        HistoryButton                   matlab.ui.control.RadioButton
        MCMButton                       matlab.ui.control.RadioButton
        LiteraryartsButton              matlab.ui.control.RadioButton
        Q8WhichpetwouldyouratherhaveButtonGroup_2  matlab.ui.container.ButtonGroup
        BeaglepuppyButton_2             matlab.ui.control.RadioButton
        BlackkittenButton_2             matlab.ui.control.RadioButton
        GoldenretrieverpuppyButton_2    matlab.ui.control.RadioButton
        AustralianshepherdpuppyButton_2  matlab.ui.control.RadioButton
        CalicokittenButton_2            matlab.ui.control.RadioButton
        Q8WhichpetwouldyouratherhaveButtonGroup  matlab.ui.container.ButtonGroup
        BeaglepuppyButton               matlab.ui.control.RadioButton
        BlackkittenButton               matlab.ui.control.RadioButton
        GoldenretrieverpuppyButton      matlab.ui.control.RadioButton
        AustralianshepherdpuppyButton   matlab.ui.control.RadioButton
        CalicokittenButton              matlab.ui.control.RadioButton
        Q7WhatisyourfavoritecolorButtonGroup  matlab.ui.container.ButtonGroup
        RedButton                       matlab.ui.control.RadioButton
        YellowButton                    matlab.ui.control.RadioButton
        OrangeButton                    matlab.ui.control.RadioButton
        BlueButton                      matlab.ui.control.RadioButton
        GreenButton                     matlab.ui.control.RadioButton
        Q6WhichroledoyouhaveinyourfriendgroupButtonGroup  matlab.ui.container.ButtonGroup
        DadfriendButton                 matlab.ui.control.RadioButton
        TheonepeoplegotoforadviceButton  matlab.ui.control.RadioButton
        CrazyuncleButton                matlab.ui.control.RadioButton
        ClassclownButton                matlab.ui.control.RadioButton
        MomfriendButton                 matlab.ui.control.RadioButton
        Q5WhichwouldyouratherdoinyourfreetimeButtonGroup  matlab.ui.container.ButtonGroup
        GooutButton                     matlab.ui.control.RadioButton
        DraworpaintButton               matlab.ui.control.RadioButton
        GolfButton                      matlab.ui.control.RadioButton
        PlaysoccerButton                matlab.ui.control.RadioButton
        PilatesmeditationButton         matlab.ui.control.RadioButton
        Q4WhoisyourfavoriteoftheseartistsButtonGroup  matlab.ui.container.ButtonGroup
        ABoogieButton                   matlab.ui.control.RadioButton
        SiaButton                       matlab.ui.control.RadioButton
        LewisCapaldiButton              matlab.ui.control.RadioButton
        SteveAokiButton                 matlab.ui.control.RadioButton
        LizzoButton                     matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            
score_harry = 0; %% INITIALIZE SCORES AND SET ALL TO 0
score_louis = 0;
score_niall = 0;
score_zayn = 0;
score_liam = 0;

%%QUESTION 1
if app.fruitsandveggiesButton.Value == true
      score_harry = score_harry + 1;
elseif app.fastfoodButton.Value ==true
      score_louis = score_louis + 1;
elseif app.WhoneedsfoodwhenyouhavebeerButton.Value ==true
      score_niall = score_niall + 1;
elseif app.pastaButton.Value ==true
      score_zayn = score_zayn + 1;
else app.MexicanfoodButton.Value ==true
      score_liam = score_liam + 1;
end

%%QUESTION 2
if app.summerButton.Value ==true
      score_harry = score_harry + 1;
elseif app.winterButton.Value ==true
      score_louis = score_louis + 1;
elseif app.springButton.Value ==true
      score_niall = score_niall + 1;
elseif app.fallButton.Value ==true
      score_zayn = score_zayn + 1;
else app.IlikethemallButton.Value ==true;
      score_liam = score_liam + 4;
end
disp(app.IlikethemallButton)

%%QUESTION 3
if app.iceskatingButton.Value ==true
      score_harry = score_harry + 1;
elseif app.goingforadriveButton.Value ==true
      score_louis = score_louis + 1;
elseif app.stayinginandplayinggamesButton.Value ==true
      score_niall = score_niall + 1;
elseif app.fancydinnerdateButton.Value ==true
      score_zayn = score_zayn + 1;
else app.goingtoacarnivalButton.Value ==true;
      score_liam = score_liam + 1;
end

%%QUESTION 4
if app.LizzoButton.Value == true
     score_harry = score_harry + 1;
elseif app.SteveAokiButton.Value == true
     score_louis = score_louis + 1;
elseif app.LewisCapaldiButton.Value == true
     score_niall = score_niall + 1;
elseif app.SiaButton.Value== true
     score_zayn = score_zayn + 1;
else app.ABoogieButton.Value == true
   score_liam = score_liam + 1;
end

%%QUESTION 5
if app.PilatesmeditationButton.Value == true
     score_harry = score_harry + 1;
elseif app.PlaysoccerButton.Value == true
     score_louis = score_louis + 1;
elseif app.GolfButton.Value == true
     score_niall = score_niall + 1;
elseif app.DraworpaintButton.Value == true
     score_zayn = score_zayn + 1;
else app.GooutButton.Value == true
   score_liam = score_liam + 1;
end

%%QUESTION 6
if app.MomfriendButton.Value == true
     score_harry = score_harry + 1;
elseif app.ClassclownButton.Value == true
     score_louis = score_louis + 1;
elseif app.CrazyuncleButton.Value == true
     score_niall = score_niall + 1;
elseif app.TheonepeoplegotoforadviceButton.Value == true
     score_zayn = score_zayn + 1;
else app.DadfriendButton.Value == true
   score_liam = score_liam + 1;
end

%%QUESTION 7
if app.GreenButton.Value == true
   score_harry = score_harry + 1;
elseif app.BlueButton.Value == true
   score_louis = score_louis + 1;
elseif app.OrangeButton.Value == true
   score_niall = score_niall + 1;
elseif app.YellowButton.Value == true
   score_zayn = score_zayn + 1;
else app.RedButton.Value == true
   score_liam = score_liam + 1;
end

%%QUESTION 8
if app.CalicokittenButton.Value == true
   score_harry = score_harry + 1;
elseif app.AustralianshepherdpuppyButton.Value == true
   score_louis = score_louis + 1;
elseif app.GoldenretrieverpuppyButton.Value == true
   score_niall = score_niall + 1;
elseif app.BlackkittenButton.Value == true
   score_zayn = score_zayn + 1;
else app.BeaglepuppyButton.Value == true
   score_liam = score_liam + 1;
end

%%QUESTION 9
if app.LiteraryartsButton.Value == true
   score_harry = score_harry + 1;
elseif app.MCMButton.Value == true
   score_louis = score_louis + 1;
elseif app.HistoryButton.Value == true
   score_niall = score_niall + 1;
elseif app.VISAButton.Value == true
   score_zayn = score_zayn + 1;
else app.APMAEconwithanentrepreneurshipcertificateButton.Value == true
   score_liam = score_liam + 1;
end

%%RESULTS SECTION
if score_harry > score_louis & score_harry > score_niall & score_harry > score_zayn & score_harry > score_liam;
   disp('Harry Styles!');
elseif score_louis > score_harry & score_louis > score_niall & score_louis > score_zayn & score_louis > score_liam;
   disp('Louis Tomlinson!');
elseif score_niall > score_harry & score_niall > score_louis & score_niall > score_zayn & score_niall > score_liam;
   disp('Niall Horan!');
elseif score_zayn > score_harry & score_zayn > score_niall & score_zayn > score_liam & score_zayn > score_louis;
   disp('Zayn Malik!');
else score_liam > score_harry & score_liam > score_niall & score_liam > score_zayn & score_liam > score_louis;
   disp('Liam Payne!');
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Q4WhoisyourfavoriteoftheseartistsButtonGroup = uibuttongroup(app.UIFigure);
            app.Q4WhoisyourfavoriteoftheseartistsButtonGroup.Title = 'Q4 Who is your favorite of these artists?';
            app.LizzoButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.LizzoButton.Text = 'Lizzo';
            app.LizzoButton.Value = true;
            app.SteveAokiButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.SteveAokiButton.Text = 'Steve Aoki';
            app.LewisCapaldiButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.LewisCapaldiButton.Text = 'Lewis Capaldi';
            app.SiaButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.SiaButton.Text = 'Sia';
            app.ABoogieButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.ABoogieButton.Text = 'A Boogie';
            app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup = uibuttongroup(app.UIFigure);
            app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup.Title = 'Q5 Which would you rather do in your free time?';
            app.PilatesmeditationButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.PilatesmeditationButton.Text = 'Pilates & meditation';
            app.PilatesmeditationButton.Value = true;
            app.PlaysoccerButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.PlaysoccerButton.Text = 'Play soccer';
            app.GolfButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.GolfButton.Text = 'Golf';
            app.DraworpaintButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.DraworpaintButton.Text = 'Draw or paint';
            app.GooutButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.GooutButton.Text = 'Go out';
            app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup = uibuttongroup(app.UIFigure);
            app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup.Title = 'Q6 Which role do you have in your friend group?';
            app.MomfriendButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.MomfriendButton.Text = 'Mom friend';
            app.MomfriendButton.Value = true;
            app.ClassclownButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.ClassclownButton.Text = 'Class clown';
            app.CrazyuncleButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.CrazyuncleButton.Text = 'Crazy uncle';
            app.TheonepeoplegotoforadviceButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.TheonepeoplegotoforadviceButton.Text = 'The one people go to for advice';
            app.DadfriendButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.DadfriendButton.Text = 'Dad friend';
            app.Q7WhatisyourfavoritecolorButtonGroup = uibuttongroup(app.UIFigure);
            app.Q7WhatisyourfavoritecolorButtonGroup.Title = 'Q7 What is your favorite color?';
            app.GreenButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.GreenButton.Text = 'Green';
            app.GreenButton.Value = true;
            app.BlueButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.OrangeButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.OrangeButton.Text = 'Orange';
            app.YellowButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.YellowButton.Text = 'Yellow';
            app.RedButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.RedButton.Text = 'Red';
            app.Q8WhichpetwouldyouratherhaveButtonGroup = uibuttongroup(app.UIFigure);
            app.Q8WhichpetwouldyouratherhaveButtonGroup.Title = 'Q8 Which pet would you rather have?';
            app.CalicokittenButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.CalicokittenButton.Text = {'Calico kitten'; ''};
            app.CalicokittenButton.Value = true;
            app.AustralianshepherdpuppyButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.AustralianshepherdpuppyButton.Text = 'Australian shepherd puppy ';
            app.GoldenretrieverpuppyButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.GoldenretrieverpuppyButton.Text = 'Golden retriever puppy';
            app.BlackkittenButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.BlackkittenButton.Text = 'Black kitten';
            app.BeaglepuppyButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.BeaglepuppyButton.Text = 'Beagle puppy ';
            app.Q8WhichpetwouldyouratherhaveButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.Q8WhichpetwouldyouratherhaveButtonGroup_2.Title = 'Q8 Which pet would you rather have?';
            app.CalicokittenButton_2 = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_2);
            app.CalicokittenButton_2.Text = {'Calico kitten'; ''};
            app.CalicokittenButton_2.Value = true;
            app.AustralianshepherdpuppyButton_2 = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_2);
            app.AustralianshepherdpuppyButton_2.Text = 'Australian shepherd puppy ';
            app.GoldenretrieverpuppyButton_2 = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_2);
            app.GoldenretrieverpuppyButton_2.Text = 'Golden retriever puppy';
            app.BlackkittenButton_2 = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_2);
            app.BlackkittenButton_2.Text = 'Black kitten';
            app.BeaglepuppyButton_2 = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_2);
            app.BeaglepuppyButton_2.Text = 'Beagle puppy ';
            app.Q8WhichpetwouldyouratherhaveButtonGroup_3 = uibuttongroup(app.UIFigure);
            app.Q8WhichpetwouldyouratherhaveButtonGroup_3.Title = 'Q9 If you weren’t studying CLPS, what else would you want to study at Brown?';
            app.LiteraryartsButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_3);
            app.LiteraryartsButton.Text = 'Literary arts ';
            app.LiteraryartsButton.Value = true;
            app.MCMButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_3);
            app.MCMButton.Text = 'MCM';
            app.HistoryButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_3);
            app.HistoryButton.Text = 'History';
            app.VISAButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_3);
            app.VISAButton.Text = 'VISA';
            app.APMAEconwithanentrepreneurshipcertificateButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_3);
            app.APMAEconwithanentrepreneurshipcertificateButton.Text = 'APMA Econ with an entrepreneurship certificate ';
            app.Q3WhatwouldyouridealfirstdatebeButtonGroup = uibuttongroup(app.UIFigure);
            app.Q3WhatwouldyouridealfirstdatebeButtonGroup.Title = 'Q3 What would your ideal first date be?';
            app.iceskatingButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.iceskatingButton.Text = 'ice skating';
            app.iceskatingButton.Value = true;
            app.goingforadriveButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.goingforadriveButton.Text = 'going for a drive';
            app.stayinginandplayinggamesButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.stayinginandplayinggamesButton.Text = 'staying in and playing games';
            app.fancydinnerdateButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.fancydinnerdateButton.Text = 'fancy dinner date';
            app.goingtoacarnivalButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.goingtoacarnivalButton.Text = 'going to a carnival';
            app.Q2WhatisyourfavoriteseasonButtonGroup = uibuttongroup(app.UIFigure);
            app.Q2WhatisyourfavoriteseasonButtonGroup.Title = 'Q2 What is your favorite season?';
            app.summerButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.summerButton.Text = 'summer';
            app.summerButton.Value = true;
            app.winterButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.winterButton.Text = 'winter';
            app.springButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.springButton.Text = 'spring';
            app.fallButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.fallButton.Text = 'fall';
            app.IlikethemallButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.IlikethemallButton.Text = 'I like them all!';
            app.Q1WhatisyourfavoritefoodButtonGroup = uibuttongroup(app.UIFigure);
            app.Q1WhatisyourfavoritefoodButtonGroup.Title = 'Q1 What is your favorite food?';
            app.fruitsandveggiesButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.fruitsandveggiesButton.Text = 'fruits and veggies';
            app.fruitsandveggiesButton.Value = true;
            app.fastfoodButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.fastfoodButton.Text = 'fast food';
            app.WhoneedsfoodwhenyouhavebeerButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.WhoneedsfoodwhenyouhavebeerButton.Text = 'Who needs food when you have beer?';
            app.pastaButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.pastaButton.Text = 'pasta!!';
            app.MexicanfoodButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.MexicanfoodButton.Text = 'Mexican food';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = design_page_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end